classdef AutoSimGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AutoSimGUIUIFigure            matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        LoadConfigMenu                matlab.ui.container.Menu
        SaveConfigMenu                matlab.ui.container.Menu
        AboutMenu                     matlab.ui.container.Menu
        TabGroup                      matlab.ui.container.TabGroup
        RunSimulationTab              matlab.ui.container.Tab
        SetPathofSimulinkModelLabel   matlab.ui.control.Label
        SetPathofSimulinkModelEditField  matlab.ui.control.EditField
        DLGPathofSimulinkModelButton  matlab.ui.control.Button
        SetPathofMessdatenEditFieldLabel  matlab.ui.control.Label
        SetPathofMessdatenEditField   matlab.ui.control.EditField
        DLGPathofMessdatenButton      matlab.ui.control.Button
        TimefromSpinnerLabel          matlab.ui.control.Label
        TimefromSpinner               matlab.ui.control.Spinner
        TimetoSpinnerLabel            matlab.ui.control.Label
        TimetoSpinner                 matlab.ui.control.Spinner
        DurationEditFieldLabel        matlab.ui.control.Label
        DurationEditField             matlab.ui.control.NumericEditField
        InputListEditFieldLabel       matlab.ui.control.Label
        InputListEditField            matlab.ui.control.EditField
        OutputListEditFieldLabel      matlab.ui.control.Label
        OutputListEditField           matlab.ui.control.EditField
        RunButton                     matlab.ui.control.Button
        CustomPlotButton              matlab.ui.control.Button
        DLGPathofPairsButton          matlab.ui.control.Button
        SetPathofPairsEditFieldLabel  matlab.ui.control.Label
        SetPathofPairsEditField       matlab.ui.control.EditField
        InputPairsTab                 matlab.ui.container.Tab
        InputPairsListBoxLabel        matlab.ui.control.Label
        InputPairsListBox             matlab.ui.control.ListBox
        UpdateInputListButton         matlab.ui.control.Button
        OutputPairsTab                matlab.ui.container.Tab
        OutputPairsListBoxLabel       matlab.ui.control.Label
        OutputPairsListBox            matlab.ui.control.ListBox
        UpdateOutputListButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        % tables for config
        configs = table({'Model Path';'Messdaten Path';'Time from';'Time to';'Input List';'Output List';'Pairs Path'}, ...
                        {'';'';'';'';'';'';''},...
                        'VariableNames',{'Item','Value'})
        input_pairs_table = {}
        output_pairs_table = {}
        
        % parsed settings for running
        model_path = ""
        messdaten_path = ""
        time_from = double(0)
        time_to = double(0)
        duration = double(0)
        input_list = ""
        output_list = ""
        pairs_path = ""
        
        % processed settings
        inputs_pairs = {}
        outputs_pairs = {}
        
        % messdaten
        messdaten
        messdaten_name
        idx_start
        idx_end
    end
    
    properties (Access = public)
    end
    
    methods (Access = private)
        
        function bRet = readConfig(app, config_path)
            bRet = true;
            if ~isfile(config_path)
                msgbox('Err: Cannot find config file!');
                bRet = false;
                return
            end
            
            lastUIName = app.AutoSimGUIUIFigure.Name;
            app.AutoSimGUIUIFigure.Name = [app.AutoSimGUIUIFigure.Name, ' - Loading Config'];
            
            app.configs = readtable(config_path, 'Sheet','Run');

            app.model_path = strtrim(string(app.configs.Value(ismember(app.configs.Item, 'Model Path'))));
            app.messdaten_path = strtrim(string(app.configs.Value(ismember(app.configs.Item, 'Messdaten Path'))));
            app.time_from = double(strtrim(string(app.configs.Value(ismember(app.configs.Item, 'Time from')))));
            app.time_to = double(strtrim(string(app.configs.Value(ismember(app.configs.Item, 'Time to')))));
            app.duration = double(app.time_to-app.time_from);
            app.input_list = strtrim(string(app.configs.Value(ismember(app.configs.Item, 'Input List'))));
            app.output_list = strtrim(string(app.configs.Value(ismember(app.configs.Item, 'Output List'))));
            app.pairs_path = strtrim(string(app.configs.Value(ismember(app.configs.Item, 'Pairs Path'))));
            
            app.updateRunSimTabUI;
            
            app.readPairs;
            
            app.AutoSimGUIUIFigure.Name = lastUIName;
            
            assignin('base', 'simin', []); 
            assignin('base', 'simout', []); % evalin
        end
        
        function readPairs(app)
            app.input_pairs_table = readtable(app.pairs_path, 'Sheet', 'Input');
            app.output_pairs_table = readtable(app.pairs_path, 'Sheet', 'Output');
                       
            app.inputs_pairs = strtrim([app.input_pairs_table.InCarMaker, app.input_pairs_table.InMessdaten]);
            app.outputs_pairs = strtrim([app.output_pairs_table.InCarMaker, app.output_pairs_table.InMessdaten]);
            
            app.updateInputPairsTabUI;
            app.updateOutputPairsTabUI;
        end
    
        function updateConfigstable(app)
            app.configs.Value(ismember(app.configs.Item, 'Model Path')) = {convertStringsToChars(app.model_path)};
            app.configs.Value(ismember(app.configs.Item, 'Messdaten Path')) = {convertStringsToChars(app.messdaten_path)};
            app.configs.Value(ismember(app.configs.Item, 'Time from')) = {num2str(app.time_from)};
            app.configs.Value(ismember(app.configs.Item, 'Time to')) = {num2str(app.time_to)};
            app.configs.Value(ismember(app.configs.Item, 'Input List')) = {convertStringsToChars(app.input_list)};
            app.configs.Value(ismember(app.configs.Item, 'Output List')) = {convertStringsToChars(app.output_list)};
            app.configs.Value(ismember(app.configs.Item, 'Pairs Path')) = {convertStringsToChars(app.pairs_path)};
        end
    
        function load_messdaten(app)
            t_messdaten = load(app.messdaten_path);
            t_messdaten_fields = fieldnames(t_messdaten);
            app.messdaten_name = t_messdaten_fields{1};
            app.messdaten = eval(['t_messdaten.', app.messdaten_name]);
        end
        
        function idx = find_string_index(app, S, Str)
            len = length(Str);
            idx = zeros(len,1);
            for i=1:len
                idx(i) = find(strcmp(S,Str(i)));
            end
        end
        
        function timeseries4simin = get_timeseries4simin(app)
            ts = [];
            inputs = cellstr(strtrim(split(app.input_list,','))); 
            times = app.messdaten.X(1).Data(app.idx_start:app.idx_end)-app.messdaten.X(1).Data(app.idx_start);
            for i=1:length(inputs)
                idx_name = app.find_string_index(app.inputs_pairs(:,1),inputs(i));
                idx = app.find_string_index({app.messdaten.Y.Name}, app.inputs_pairs(idx_name,2));
                ts = [ts, app.messdaten.Y(idx).Data(app.idx_start:app.idx_end)'];
            end
            timeseries4simin = timeseries(ts,times);
        end
        
        function runSim(app)
            app.load_messdaten;
            
            t_index = find(app.messdaten.X(1).Data>=app.time_from & app.messdaten.X(1).Data<=app.time_to);
            app.idx_start = t_index(1);
            app.idx_end = t_index(length(t_index));
            
            simin = app.get_timeseries4simin;
            assignin('base', 'simin', simin);
            assignin('base', 'messdaten', app.messdaten);
            assignin('base', 'messdaten_name', convertStringsToChars(app.messdaten_name));
            assignin('base', 'idx_start', app.idx_start);
            assignin('base', 'idx_end', app.idx_end);
            
            SIM_Outputs = sim(app.model_path, 'ReturnWorkspaceOutputs','on');
            assignin('base', 'simout', SIM_Outputs.simout);
            
            app.updateConfigstable;
            assignin('base', 'configs', app.configs);
            assignin('base', 'inputs_pairs', app.inputs_pairs);
            assignin('base', 'inputs', cellstr(strtrim(split(app.input_list,','))));
            assignin('base', 'outputs_pairs', app.outputs_pairs);
            assignin('base', 'outputs', cellstr(strtrim(split(app.output_list,','))));
        end
        
        function updateRunSimTabUI(app)
            app.SetPathofSimulinkModelEditField.Value = app.model_path;
            app.SetPathofMessdatenEditField.Value = app.messdaten_path;
            app.SetPathofPairsEditField.Value = app.pairs_path;
            app.TimefromSpinner.Value = app.time_from;
            app.TimetoSpinner.Value = app.time_to;
            app.DurationEditField.Value = app.duration;
            app.InputListEditField.Value = app.input_list;
            app.OutputListEditField.Value = app.output_list;
        end
        
        function updateParameterInRunSimTab(app)
            app.model_path = app.SetPathofSimulinkModelEditField.Value;
            app.messdaten_path = app.SetPathofMessdatenEditField.Value;
            app.pairs_path = app.SetPathofPairsEditField.Value;
            app.time_from = app.TimefromSpinner.Value;
            app.time_to = app.TimetoSpinner.Value;
            app.duration = app.DurationEditField.Value;
            app.input_list = app.InputListEditField.Value;
            app.output_list = app.OutputListEditField.Value;
        end
        
        function updateInputPairsTabUI(app)
            app.InputPairsListBox.Value = {};
            app.InputPairsListBox.Items = {};
            app.InputPairsListBox.ItemsData = {};
            s = size(app.inputs_pairs);
            for i=1:s(1)
                app.InputPairsListBox.Items = [app.InputPairsListBox.Items, [app.inputs_pairs{i,1}, ' <--> ', app.inputs_pairs{i,2}]];
                app.InputPairsListBox.ItemsData = [app.InputPairsListBox.ItemsData, app.inputs_pairs(i,1)];
            end
        end
        
        function updateOutputPairsTabUI(app)
            app.OutputPairsListBox.Value = {};
            app.OutputPairsListBox.Items = {};
            app.OutputPairsListBox.ItemsData = {};
            s = size(app.outputs_pairs);
            for i=1:s(1)
                app.OutputPairsListBox.Items = [app.OutputPairsListBox.Items, [app.outputs_pairs{i,1}, ' <--> ', app.outputs_pairs{i,2}]];
                app.OutputPairsListBox.ItemsData = [app.OutputPairsListBox.ItemsData, app.outputs_pairs(i,1)];
            end
        end
        
        function defaultSimoutPlot(app)
            simout = evalin('base', 'simout');
            figure('Name', convertStringsToChars(app.messdaten_name), 'NumberTitle', 'off');
            outputs = cellstr(strtrim(split(app.output_list,','))); 
            % slice time series
            all_simtime = (app.idx_end-app.idx_start+1)/100;
            ts = simout.Time>0 & simout.Time<=all_simtime;
            t_span = round(length(ts)/((app.idx_end-app.idx_start+1)*10));
            simout_time = simout.Time(ts);
            % begin loop
            len = size(outputs,1);
            for i=1:len
                idx_pairs_list(i) = app.find_string_index(app.outputs_pairs(:,1), outputs(i));
                idx_messdatenX_list(i) = app.find_string_index({app.messdaten.X.Raster}, app.outputs_pairs(idx_pairs_list(i),2));
                idx_messdatenY_list(i) = app.find_string_index({app.messdaten.Y.Name}, app.outputs_pairs(idx_pairs_list(i),2));
                                 
                % plot(--Simdaten--, --Messdaten--);
                simout_data_i = simout.Data(ts, i);
                subplot(ceil(len/3),3,i), plot(simout_time(1:t_span:end), simout_data_i(1:t_span:end), ...
                    app.messdaten.X(1).Data(app.idx_start:app.idx_end) - app.messdaten.X(1).Data(app.idx_start), ... 
                    app.messdaten.Y(idx_messdatenY_list(i)).Data(app.idx_start:app.idx_end));
                title(cell2str(app.outputs_pairs(idx_pairs_list(i),:),' / '), 'Interpreter', 'none');
                legend('Simdaten', 'Messdaten');
                xlabel('time (s)');
            end
        end
    end
    

    methods (Access = private)

        % Menu selected function: LoadConfigMenu
        function LoadConfigMenuSelected(app, event)
            [filename, path] = uigetfile({'*.xlsx';'*.*'},'Open Config File');
            figure(app.AutoSimGUIUIFigure);
            if filename == 0
                return
            end
            if app.readConfig([path, filename])
                msgbox('Successfully loaded config file!');
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            lastUIName = app.AutoSimGUIUIFigure.Name;
            app.AutoSimGUIUIFigure.Name = [app.AutoSimGUIUIFigure.Name, ' - Running'];
            
            startDir = pwd;
            [fileDir,model_name,ext] = fileparts(app.model_path);
            cd(fileDir);
            app.runSim; 
            cd(startDir);
            
            app.AutoSimGUIUIFigure.Name = lastUIName;
            
            lastUIName = app.AutoSimGUIUIFigure.Name;
            app.AutoSimGUIUIFigure.Name = [app.AutoSimGUIUIFigure.Name, ' - Preparing for plots'];
            
            app.defaultSimoutPlot; 
            
            app.AutoSimGUIUIFigure.Name = lastUIName;
        end

        % Value changed function: SetPathofSimulinkModelEditField
        function SetPathofSimulinkModelEditFieldValueChanged(app, event)
            value = app.SetPathofSimulinkModelEditField.Value;
            app.model_path = value;
        end

        % Value changed function: SetPathofMessdatenEditField
        function SetPathofMessdatenEditFieldValueChanged(app, event)
            value = app.SetPathofMessdatenEditField.Value;
            app.messdaten_path = value;
        end

        % Value changed function: SetPathofPairsEditField
        function SetPathofPairsEditFieldValueChanged(app, event)
            value = app.SetPathofPairsEditField.Value;
            app.pairs_path = value;
        end

        % Value changed function: TimefromSpinner
        function TimefromSpinnerValueChanged(app, event)
            value = app.TimefromSpinner.Value;
            app.time_from = double(value);
            app.duration = double(app.time_to-app.time_from);
            app.DurationEditField.Value = app.duration;
        end

        % Value changed function: TimetoSpinner
        function TimetoSpinnerValueChanged(app, event)
            value = app.TimetoSpinner.Value;
            app.time_to = double(value);
            app.duration = double(app.time_to-app.time_from);
            app.DurationEditField.Value = app.duration;
        end

        % Value changed function: DurationEditField
        function DurationEditFieldValueChanged(app, event)
            value = app.DurationEditField.Value;
            app.duration = value;
        end

        % Value changed function: InputListEditField
        function InputListEditFieldValueChanged(app, event)
            value = app.InputListEditField.Value;
            app.input_list = value;
        end

        % Value changed function: OutputListEditField
        function OutputListEditFieldValueChanged(app, event)
            value = app.OutputListEditField.Value;
            app.output_list = value;
        end

        % Button pushed function: UpdateInputListButton
        function UpdateInputListButtonPushed(app, event)
            len = length(app.InputPairsListBox.Value);
            str = '';
            for i=1:len
                str = [str, app.InputPairsListBox.Value{i}];
                if i~=len
                    str = [str, ', '];
                end
            end
            app.input_list = str;
            app.updateRunSimTabUI;
        end

        % Button pushed function: UpdateOutputListButton
        function UpdateOutputListButtonPushed(app, event)
            len = length(app.OutputPairsListBox.Value);
            str = '';
            for i=1:len
                str = [str, app.OutputPairsListBox.Value{i}];
                if i~=len
                    str = [str, ', '];
                end
            end
            app.output_list = str;
            app.updateRunSimTabUI;
        end

        % Button pushed function: DLGPathofSimulinkModelButton
        function DLGPathofSimulinkModelButtonPushed(app, event)
            [filename, path] = uigetfile({'*.slx;*.mdl';'*.*'},'Open Simulink Model File');
            figure(app.AutoSimGUIUIFigure);
            if filename == 0
                return
            end
            if ~isfile([path, filename])
                msgbox('Err: Cannot find Simulink Model File!');
                return
            end
            app.model_path = [path, filename];
            app.updateRunSimTabUI;
        end

        % Button pushed function: DLGPathofMessdatenButton
        function DLGPathofMessdatenButtonPushed(app, event)
            [filename, path] = uigetfile({'*.mat';'*.*'},'Open Messdaten File');
            figure(app.AutoSimGUIUIFigure);
            if filename == 0
                return
            end
            if ~isfile([path, filename])
                msgbox('Err: Cannot find Messdaten File!');
                return
            end
            app.messdaten_path = [path, filename];
            app.updateRunSimTabUI;
        end

        % Button pushed function: DLGPathofPairsButton
        function DLGPathofPairsButtonPushed(app, event)
            [filename, path] = uigetfile({'Pairs.xlsx';'*.xlsx';'*.*'},'Open Pairs File');
            figure(app.AutoSimGUIUIFigure);
            if filename == 0
                return
            end
            if ~isfile([path, filename])
                msgbox('Err: Cannot find Pairs File!');
                return
            end
            app.pairs_path = [path, filename];
            app.updateRunSimTabUI;
            app.readPairs;
        end

        % Menu selected function: SaveConfigMenu
        function SaveConfigMenuSelected(app, event)
            app.updateConfigstable;
            [filename, path] = uiputfile({'*.xlsx';'*.*'},'Save Config File');
            figure(app.AutoSimGUIUIFigure);
            if filename == 0
                return
            end
            lastUIName = app.AutoSimGUIUIFigure.Name;
            app.AutoSimGUIUIFigure.Name = [app.AutoSimGUIUIFigure.Name, ' - Saving Config'];
            
            writetable(app.configs, [path, filename], 'Sheet', 'Run');
            
            app.AutoSimGUIUIFigure.Name = lastUIName;
            
            msgbox('Successfully saved config file!');
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            msgbox('Made by Ted');
        end

        % Button pushed function: CustomPlotButton
        function CustomPlotButtonPushed(app, event)
            if isfile('AutoSimGUI_Custom_Plot.m')
                evalin('base', 'AutoSimGUI_Custom_Plot;');
            else
                [filename, path] = uigetfile({'*.m';'*.*'},'Run Plot Script');
                figure(app.AutoSimGUIUIFigure);
                if filename == 0
                    return
                end
                startDir = pwd;
                [fileDir,script,ext] = fileparts([path, filename]);
                cd(fileDir);
                evalin('base', strcat(script, ';'));
                cd(startDir);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AutoSimGUIUIFigure = uifigure;
            app.AutoSimGUIUIFigure.Name = 'AutoSimGUI';
            app.FileMenu = uimenu(app.AutoSimGUIUIFigure);
            app.FileMenu.Text = 'File';
            app.LoadConfigMenu = uimenu(app.FileMenu);
            app.LoadConfigMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadConfigMenuSelected, true);
            app.LoadConfigMenu.Text = 'Load Config';
            app.SaveConfigMenu = uimenu(app.FileMenu);
            app.SaveConfigMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveConfigMenuSelected, true);
            app.SaveConfigMenu.Text = 'Save Config';
            app.AboutMenu = uimenu(app.AutoSimGUIUIFigure);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.TabGroup = uitabgroup(app.AutoSimGUIUIFigure);
            app.RunSimulationTab = uitab(app.TabGroup);
            app.RunSimulationTab.Title = 'Run Simulation';
            app.SetPathofSimulinkModelLabel = uilabel(app.RunSimulationTab);
            app.SetPathofSimulinkModelLabel.Text = 'Set Path of Simulink Model';
            app.SetPathofSimulinkModelEditField = uieditfield(app.RunSimulationTab, 'text');
            app.SetPathofSimulinkModelEditField.ValueChangedFcn = createCallbackFcn(app, @SetPathofSimulinkModelEditFieldValueChanged, true);
            app.DLGPathofSimulinkModelButton = uibutton(app.RunSimulationTab, 'push');
            app.DLGPathofSimulinkModelButton.ButtonPushedFcn = createCallbackFcn(app, @DLGPathofSimulinkModelButtonPushed, true);
            app.DLGPathofSimulinkModelButton.Text = '...';
            app.SetPathofMessdatenEditFieldLabel = uilabel(app.RunSimulationTab);
            app.SetPathofMessdatenEditFieldLabel.Text = 'Set Path of Messdaten';
            app.SetPathofMessdatenEditField = uieditfield(app.RunSimulationTab, 'text');
            app.SetPathofMessdatenEditField.ValueChangedFcn = createCallbackFcn(app, @SetPathofMessdatenEditFieldValueChanged, true);
            app.DLGPathofMessdatenButton = uibutton(app.RunSimulationTab, 'push');
            app.DLGPathofMessdatenButton.ButtonPushedFcn = createCallbackFcn(app, @DLGPathofMessdatenButtonPushed, true);
            app.DLGPathofMessdatenButton.Text = '...';
            app.TimefromSpinnerLabel = uilabel(app.RunSimulationTab);
            app.TimefromSpinnerLabel.Text = 'Time from';
            app.TimefromSpinner = uispinner(app.RunSimulationTab);
            app.TimefromSpinner.ValueChangedFcn = createCallbackFcn(app, @TimefromSpinnerValueChanged, true);
            app.TimetoSpinnerLabel = uilabel(app.RunSimulationTab);
            app.TimetoSpinnerLabel.Text = 'Time to';
            app.TimetoSpinner = uispinner(app.RunSimulationTab);
            app.TimetoSpinner.ValueChangedFcn = createCallbackFcn(app, @TimetoSpinnerValueChanged, true);
            app.DurationEditFieldLabel = uilabel(app.RunSimulationTab);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.RunSimulationTab, 'numeric');
            app.DurationEditField.ValueChangedFcn = createCallbackFcn(app, @DurationEditFieldValueChanged, true);
            app.DurationEditField.Tooltip = {'Copy this to CarMaker'};
            app.InputListEditFieldLabel = uilabel(app.RunSimulationTab);
            app.InputListEditFieldLabel.Text = 'Input List';
            app.InputListEditField = uieditfield(app.RunSimulationTab, 'text');
            app.InputListEditField.ValueChangedFcn = createCallbackFcn(app, @InputListEditFieldValueChanged, true);
            app.InputListEditField.Tooltip = {'Copy this to CarMaker'};
            app.OutputListEditFieldLabel = uilabel(app.RunSimulationTab);
            app.OutputListEditFieldLabel.Text = 'Output List';
            app.OutputListEditField = uieditfield(app.RunSimulationTab, 'text');
            app.OutputListEditField.ValueChangedFcn = createCallbackFcn(app, @OutputListEditFieldValueChanged, true);
            app.OutputListEditField.Tooltip = {'Copy this to CarMaker'};
            app.RunButton = uibutton(app.RunSimulationTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.CustomPlotButton = uibutton(app.RunSimulationTab, 'push');
            app.CustomPlotButton.ButtonPushedFcn = createCallbackFcn(app, @CustomPlotButtonPushed, true);
            app.CustomPlotButton.Text = 'Custom Plot';
            app.DLGPathofPairsButton = uibutton(app.RunSimulationTab, 'push');
            app.DLGPathofPairsButton.ButtonPushedFcn = createCallbackFcn(app, @DLGPathofPairsButtonPushed, true);
            app.DLGPathofPairsButton.Text = '...';
            app.SetPathofPairsEditFieldLabel = uilabel(app.RunSimulationTab);
            app.SetPathofPairsEditFieldLabel.Text = 'Set Path of Pairs';
            app.SetPathofPairsEditField = uieditfield(app.RunSimulationTab, 'text');
            app.SetPathofPairsEditField.ValueChangedFcn = createCallbackFcn(app, @SetPathofPairsEditFieldValueChanged, true);
            app.InputPairsTab = uitab(app.TabGroup);
            app.InputPairsTab.Title = 'Input Pairs';
            app.InputPairsListBoxLabel = uilabel(app.InputPairsTab);
            app.InputPairsListBoxLabel.Text = 'Input Pairs';
            app.InputPairsListBox = uilistbox(app.InputPairsTab);
            app.InputPairsListBox.Items = {};
            app.InputPairsListBox.Multiselect = 'on';
            app.InputPairsListBox.Value = {};
            app.UpdateInputListButton = uibutton(app.InputPairsTab, 'push');
            app.UpdateInputListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateInputListButtonPushed, true);
            app.UpdateInputListButton.Text = 'Update Input List';
            app.OutputPairsTab = uitab(app.TabGroup);
            app.OutputPairsTab.Title = 'Output Pairs';
            app.OutputPairsListBoxLabel = uilabel(app.OutputPairsTab);
            app.OutputPairsListBoxLabel.Text = 'Output Pairs';
            app.OutputPairsListBox = uilistbox(app.OutputPairsTab);
            app.OutputPairsListBox.Items = {};
            app.OutputPairsListBox.Multiselect = 'on';
            app.OutputPairsListBox.Value = {};
            app.UpdateOutputListButton = uibutton(app.OutputPairsTab, 'push');
            app.UpdateOutputListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateOutputListButtonPushed, true);
            app.UpdateOutputListButton.Text = 'Update Output List';
        end
    end

    methods (Access = public)

        % Construct app
        function app = AutoSimGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AutoSimGUIUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AutoSimGUIUIFigure)
        end
    end
end