classdef CheckMessdaten < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CheckMessdatenUIFigure   matlab.ui.Figure
        FileMenu                 matlab.ui.container.Menu
        OpenMenu                 matlab.ui.container.Menu
        AboutMenu                matlab.ui.container.Menu
        SelectItemsListBoxLabel  matlab.ui.control.Label
        SelectItemsListBox       matlab.ui.control.ListBox
        SubplotsLabel            matlab.ui.control.Label
        MatFullPathLabel         matlab.ui.control.Label
        ROWSSpinnerLabel         matlab.ui.control.Label
        ROWSSpinner              matlab.ui.control.Spinner
        COLSSpinnerLabel         matlab.ui.control.Label
        COLSSpinner              matlab.ui.control.Spinner
        Tree                     matlab.ui.container.Tree
        Plots1Node               matlab.ui.container.TreeNode
        CreateFigureButton       matlab.ui.control.Button
        AddButton                matlab.ui.control.Button
        NEWButton                matlab.ui.control.Button
        DELButton                matlab.ui.control.Button
        UPButton                 matlab.ui.control.Button
        DOWNButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        matPath % Path of Mat File
        matName % Name of Mat File
        messdaten % loaded Messdaten
        CountPlots = 1 % Count of Plots
        timefrom = -1; % Figure Time From
        timeto = -1; % Figure Time To
    end
    
    methods (Access = private)
        function loadMessdaten(app)
            t_messdaten = load([app.matPath, app.matName]);
            t_messdaten_fields = fieldnames(t_messdaten);
            name = t_messdaten_fields{1};
            app.messdaten = eval(['t_messdaten.', name]);
        end
        
        function item = getItemFromMessdaten(app, name)
            item.name = name;
            item.xid = find_string_index({app.messdaten.X.Raster}, {item.name});
            item.yid = find_string_index({app.messdaten.Y.Name}, {item.name});
            item.time = app.messdaten.X(item.xid).Data;
            item.data = app.messdaten.Y(item.yid).Data;
        end
    
        function err = showMessdatenInLB(app)
            err = 0;
            if ~isfield(app.messdaten, 'Y')
                err = 1;
                return;
            end
            names = {app.messdaten.Y.Name};
            app.SelectItemsListBox.Items = names;
        end
        
        function node = findTopNode(app, SelectedNode)
            node = SelectedNode;
            while isprop(node, 'Parent')
                if ~isa(node.Parent, 'matlab.ui.container.TreeNode')
                    break;
                end
                node = SelectedNode.Parent;
            end
        end
        
        function idx = findSelfPos(app, neighbors)
            for idx = 1:length(neighbors)
                if neighbors(idx) == app.Tree.SelectedNodes
                    break;
                end
            end
        end
        
        function ItemAdd(app)
            if isempty(app.SelectItemsListBox.Value)
                msgbox('<-- Please select an item!');
                return
            end
            if isempty(app.Tree.SelectedNodes)
                msgbox('Please select a node! -->');
                return
            end

            topNode = app.findTopNode(app.Tree.SelectedNodes);
            uitreenode(topNode, 'Text', string(app.SelectItemsListBox.Value));

            app.Tree.expand;
        end
        
        function NodeUP(app)
            if isempty(app.Tree.SelectedNodes)
                return
            end
            neighbors = app.Tree.SelectedNodes.Parent.Children;
            idx = app.findSelfPos(neighbors);
            if idx == 1
                return
            end
            move(app.Tree.SelectedNodes, neighbors(idx-1), 'before');            
        end
        
        function NodeDOWN(app)
            if isempty(app.Tree.SelectedNodes)
                return
            end
            neighbors = app.Tree.SelectedNodes.Parent.Children;
            idx = app.findSelfPos(neighbors);
            if idx == length(neighbors)
                return
            end
            move(app.Tree.SelectedNodes, neighbors(idx+1), 'after');       
        end
        
        function NodeNEW(app)
            app.CountPlots = app.CountPlots + 1;
            uitreenode(app.Tree, 'Text', strcat('Plots', string(app.CountPlots)));
        end
        
        function NodeDEL(app)
            if isempty(app.Tree.SelectedNodes)
                return
            end
            app.Tree.SelectedNodes.delete;
        end
        
        function GetMatFile(app)
            [app.matName, app.matPath] = uigetfile('*.mat','Open Mat File');
            figure(app.CheckMessdatenUIFigure);
            if app.matName == 0
                app.MatFullPathLabel.Text = 'ERR: NO SELECTED FILE!';
                return
            end
            app.MatFullPathLabel.Text = [app.matPath, app.matName];
            app.loadMessdaten;
            if app.showMessdatenInLB ~= 0
                app.MatFullPathLabel.Text = "ERR: CAN'T READ THIS FILE!";
            end
        end
        
        function GenFigure(app)
            rows = app.ROWSSpinner.Value;
            cols = app.COLSSpinner.Value;
            neighbors = app.Tree.Children;
            
            if isempty(app.matName)
                return
            end
            subi=0;
            figure('Name', app.matName, 'NumberTitle', 'off');
            for i=1:length(neighbors)
                subi = subi+1;
                if  rows==1 && cols==1 && subi>1
                    figure('Name', app.matName, 'NumberTitle', 'off');
                elseif subi>rows*cols
                    figure('Name', app.matName, 'NumberTitle', 'off');
                    subi = subi-rows*cols;
                    subplot(rows, cols, subi);
                else
                    subplot(rows, cols, subi);
                end
                items_count = length(neighbors(i).Children);
                if items_count < 1
                    continue
                end
                hold on
                item_name_list = cell(1,items_count);
                for j=1:items_count
                    item_name = neighbors(i).Children(j).Text;
                    item_name_list(j) = {item_name};
                    item = app.getItemFromMessdaten(item_name);
                    ts = (item.time>=app.timefrom) & ((item.time<=app.timeto)+(app.timeto<0)*1);
                    plot(item.time(ts),item.data(ts));
                    grid on
                end
                if items_count>1
                    legend(item_name_list, 'Interpreter', 'none');
                else
                    title(item_name_list(1), 'Interpreter', 'none');
                end
                xlabel('time (s)');
                hold off
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, timefrom, timeto)
            if (nargin<2)
                return;
            end
            app.timefrom = timefrom;
            app.timeto = timeto;
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            app.GetMatFile;
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            app.ItemAdd;
        end

        % Button pushed function: NEWButton
        function NEWButtonPushed(app, event)
            app.NodeNEW;
        end

        % Button pushed function: DELButton
        function DELButtonPushed(app, event)
            app.NodeDEL;
        end

        % Button pushed function: UPButton
        function UPButtonPushed(app, event)
            app.NodeUP;
        end

        % Button pushed function: DOWNButton
        function DOWNButtonPushed(app, event)
            app.NodeDOWN;
        end

        % Window key press function: CheckMessdatenUIFigure
        function CheckMessdatenUIFigureWindowKeyPress(app, event)
            key = event.Key;
            switch key
                case {'insert', 'q', 'Q'}
                    app.NodeNEW;
                case {'delete', 'e', 'E'}
                    app.NodeDEL;
                case {'w', 'W'}
                    app.NodeUP;
                case {'s', 'S'}
                    app.NodeDOWN;
                case {'a', 'A'}
                    app.ItemAdd;
                case {'o', 'O'}
                    app.GetMatFile;
                case {'f', 'F'}
                    app.GenFigure;    
                otherwise
            end
        end

        % Button pushed function: CreateFigureButton
        function CreateFigureButtonPushed(app, event)
            app.GenFigure;
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            msgbox('Made by Ted');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CheckMessdatenUIFigure = uifigure;
            app.CheckMessdatenUIFigure.Name = 'Check Messdaten';
            app.CheckMessdatenUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @CheckMessdatenUIFigureWindowKeyPress, true);
            app.FileMenu = uimenu(app.CheckMessdatenUIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.AboutMenu = uimenu(app.CheckMessdatenUIFigure);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.SelectItemsListBoxLabel = uilabel(app.CheckMessdatenUIFigure);
            app.SelectItemsListBoxLabel.Text = 'Select Items:';
            app.SelectItemsListBox = uilistbox(app.CheckMessdatenUIFigure);
            app.SelectItemsListBox.Items = {};
            app.SelectItemsListBox.Multiselect = 'on';
            app.SelectItemsListBox.Value = {};
            app.SubplotsLabel = uilabel(app.CheckMessdatenUIFigure);
            app.SubplotsLabel.Text = 'Subplots: ';
            app.MatFullPathLabel = uilabel(app.CheckMessdatenUIFigure);
            app.MatFullPathLabel.Text = 'MatFullPath';
            app.ROWSSpinnerLabel = uilabel(app.CheckMessdatenUIFigure);
            app.ROWSSpinnerLabel.Text = 'ROWS';
            app.ROWSSpinner = uispinner(app.CheckMessdatenUIFigure);
            app.ROWSSpinner.Limits = [1 Inf];
            app.ROWSSpinner.RoundFractionalValues = 'on';
            app.ROWSSpinner.Value = 1;
            app.COLSSpinnerLabel = uilabel(app.CheckMessdatenUIFigure);
            app.COLSSpinnerLabel.Text = 'COLS';
            app.COLSSpinner = uispinner(app.CheckMessdatenUIFigure);
            app.COLSSpinner.Limits = [1 Inf];
            app.COLSSpinner.RoundFractionalValues = 'on';
            app.COLSSpinner.Value = 1;
            app.Tree = uitree(app.CheckMessdatenUIFigure);
            app.Plots1Node = uitreenode(app.Tree);
            app.Plots1Node.Text = 'Plots1';
            app.CreateFigureButton = uibutton(app.CheckMessdatenUIFigure, 'push');
            app.CreateFigureButton.ButtonPushedFcn = createCallbackFcn(app, @CreateFigureButtonPushed, true);
            app.CreateFigureButton.Text = 'Create Figure';
            app.AddButton = uibutton(app.CheckMessdatenUIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add>>';
            app.NEWButton = uibutton(app.CheckMessdatenUIFigure, 'push');
            app.NEWButton.ButtonPushedFcn = createCallbackFcn(app, @NEWButtonPushed, true);
            app.NEWButton.Text = 'NEW';
            app.DELButton = uibutton(app.CheckMessdatenUIFigure, 'push');
            app.DELButton.ButtonPushedFcn = createCallbackFcn(app, @DELButtonPushed, true);
            app.DELButton.Text = 'DEL';
            app.UPButton = uibutton(app.CheckMessdatenUIFigure, 'push');
            app.UPButton.ButtonPushedFcn = createCallbackFcn(app, @UPButtonPushed, true);
            app.UPButton.Text = 'UP';
            app.DOWNButton = uibutton(app.CheckMessdatenUIFigure, 'push');
            app.DOWNButton.ButtonPushedFcn = createCallbackFcn(app, @DOWNButtonPushed, true);
            app.DOWNButton.Text = 'DOWN';
        end
    end

    methods (Access = public)

        % Construct app
        function app = CheckMessdaten(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CheckMessdatenUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CheckMessdatenUIFigure)
        end
    end
end